classdef FarField_r0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FarFieldDiffractionModelLabel  matlab.ui.control.Label
        ApertureTypeDropDownLabel      matlab.ui.control.Label
        ApertureTypeDropDown           matlab.ui.control.DropDown
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel       matlab.ui.control.Label
        SideLengthEditField            matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        CrossSectionButtonGroup        matlab.ui.container.ButtonGroup
        BothButton                     matlab.ui.control.RadioButton
        XAxisButton                    matlab.ui.control.RadioButton
        YAxisButton                    matlab.ui.control.RadioButton
        ZoomInButton                   matlab.ui.control.Button
        ZoomOutButton                  matlab.ui.control.Button
        FarField                       matlab.ui.control.UIAxes
        CrossSection                   matlab.ui.control.UIAxes
        Aperture                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ApertureTypeDropDown
        function ApertureTypeDropDownValueChanged(app, event)
            % Update appearance of the GUI
            switch app.ApertureTypeDropDown.Value
                case 'Circle'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Radius'; 
                case 'Square'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Height/Width';
                    app.SideLengthEditFieldLabel.Text = 'N/A';
                case 'Rectangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Height';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Right Triangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Height';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Single Slit'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'N/A';
                case 'Double Slit'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'Slit Spacing';
            end
            
            % Clear Current Displays
            cla(app.Aperture)
            cla(app.CrossSection)
            cla(app.FarField)
            legend(app.CrossSection, "off");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Define Aperture field
            apl = 5000; % Size of the aperture field
            ap = zeros(apl); % Define actual aperture plane
            
            % Grab Values From The GUI
            % apshape = app.ApertureTypeDropDown.Value;
            len1 = round(app.RadiusEditField.Value);
            len2 = round(app.SideLengthEditField.Value);
            app.RadiusEditField.Value = len1;
            app.SideLengthEditField.Value = len2;
            
            % Generate The Aperture, and Correct Dimension Bounds
            switch app.ApertureTypeDropDown.Value
                case 'Circle'
                    % For Plots
                    apname = 'Circular'; % Define the name of the aperure using for the title
                    linsty2 = '--r'; % Define plot style for second cross-section
                    
                    % Bounds
                    if len1 > 2500 % upper bound
                        len1 = 2500;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Aperture Generation for every x, for every y
                    for ijk=1:apl
                        for lmn=1:apl
                            % if the the point ap(ijk,lmn) is within the radius of the 
                            % circle from the center of the aperture, 
                            % the ap value at that point changes from 0 to 1
                            if round((1+apl/2-ijk)^2+(1+apl/2-lmn)^2) <= len1^2
                              ap(ijk,lmn)=1;
                            end
                        end
                    end
            
                case 'Square'
                    % For Plots
                    apname = 'Square'; % Define the name of the aperure using for the title
                    linsty2 = '--r'; % Define plot style for second cross-section
                    
                     % Bounds
                    if len1 > 4888 % upper bound
                        len1 = 4888;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Aperture Generation: 
                    % changes ap value from 0 to 1 in range of +- len1/2 heightwise
                    % and +- len1/2 lengthwise about the center of the aperture
                    ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-len1/2):round(1+apl/2+len1/2))=1;    
                    
                case 'Rectangle'
                    apname = 'Rectangular'; % Define the name of the aperure using for the title
                    
                    % Height Bounds
                    if len1 > 4998 % upper bound
                        len1 = 4998;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Width Bounds
                    if len2 > 4888 % upper bound
                        len2 = 4888;
                        app.SideLengthEditField.Value = len2;
                    elseif len2 < 1 % lower bound
                        len2 = 1;
                        app.SideLengthEditField.Value = len2;
                    else
                    end
                    
                    % Cross section line style
                    if len1 == len2
                        linsty2 = '--r'; % overlapping cross-sections
                    else
                        linsty2 = '-r';
                    end
                    
                    % Aperture Generation:
                    % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                    % +- len2/2 lengthwise about the center of the aperture
                    ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-len2/2):round(1+apl/2+len2/2))=1;
                    
                case 'Right Triangle'
                    apname = 'Triangular'; % Define the name of the aperure using for the title
                    
                    % Height Bounds
                    if len1 > 5000 % upper bound
                        len1 = 5000;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Width Bounds
                    if len2 > 5000 % upper bound
                        len2 = 5000;
                        app.SideLengthEditField.Value = len2;
                    elseif len2 < 1 % lower bound
                        len2 = 1;
                        app.SideLengthEditField.Value = len2;
                    else
                    end
                    
                    % Cross section line style
                    if len1 == len2
                        linsty2 = '--r'; % overlapping cross-sections
                    else
                        linsty2 = '-r';
                    end
                    
                    % Aperture Generation
                    m = 0; % multiplier for step length
                    dis = len2/len1; % increase in width for one increase in height
                    for ijk=round(1+apl/2-len1/2):round(1+apl/2+len1/2)
                        % m*dis is step size, increases as ijk increases
                        for lmn=round(1+apl/2-len2/2):round(1+apl/2-len2/2+m*dis)
                            ap(ijk,lmn)=1;
                        end
                        m = m+1;
                    end
                
                case 'Single Slit'
                    % For Plots
                    apname = 'Single-Slit'; % Define the name of the aperure using for the title
                    linsty2 = '-r'; % Define plot style for second cross-section
                    
                    % Height Bounds
                    if len1 > 4998 % upper bound
                        len1 = 4998;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Aperture Generation
                    slitw = 200; % Define the slit-width
                    % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                    % +- 100 lengthwise about the center of the aperture
                    ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2):round(1+apl/2+slitw/2))=1;
                                        
                case 'Double Slit'
                    % For Plots
                    apname = 'Double-Slit'; % Define the name of the aperure using for the title
                    linsty2 = '-r'; % Define plot style for second cross-section
                    
                    slitw = 175; % Define the slit-width
                    % Height Bounds
                    if len1 > 4998 % upper bound
                        len1 = 4998;
                        app.RadiusEditField.Value = len1;
                    elseif len1 < 1 % lower bound
                        len1 = 1;
                        app.RadiusEditField.Value = len1;
                    else
                    end
                    
                    % Spacing Bounds
                    if len2 > 4823 % upper bound
                        len2 = 4823;
                        app.SideLengthEditField.Value = len2;
                    elseif len2 < slitw + 3 % lower bound = slit width + 3
                        len2 = slitw + 3;
                        app.SideLengthEditField.Value = len2;
                    else
                    end
                    
                    % Aperture Generation
                    % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                    % +- 175/2 lengthwise about the points center +- len2/2 lengthwise
                    ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2-len2/2):round(1+apl/2+slitw/2-len2/2))=1;
                    ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2+len2/2):round(1+apl/2+slitw/2+len2/2))=1;
                    
            end
            
            % Plot the Aperture
            imagesc(app.Aperture,ap); % Plot image of the aperture field
            title(app.Aperture,[apname,' Aperture']); % Set the title for each plot
            app.Aperture.Colormap = gray;
            % app.Aperture.YDir = 'normal'; % Flip Command
            
             % Do the Fourier Transform for each aperture
            U = fftshift(fft2(ap));
            I_ff = abs(U).^2; % Take the magnitude of the results and square it to get the irradiance
            
            % Now for the Center-Field Cross Sections
            
            xcrossNorm = I_ff(:,apl/2+1)./max(I_ff(:,apl/2+1)); % Normalized x cross-section
            ycrossNorm = I_ff(apl/2+1,:)./max(I_ff(apl/2+1,:)); % Normalized y cross-section
            
            % Dynamically determine initial zoom

            ijk = 1;
            while xcrossNorm(ijk) < 0.0005 % find bounds for center of pattern
                ijk = ijk+1;
            end
            apl_cfX = apl-2*ijk-2;
            lmn = 1;
            while ycrossNorm(lmn) < 0.0005
                lmn = lmn+1;
            end
            apl_cfY = apl-2*lmn-2;
            if apl_cfX >= apl_cfY
                apl_cf = apl_cfX;
            else
                apl_cf = apl_cfY;
            end
            
            % define center field
            %I_cf = I_ff([1+apl/2-apl_cf/2:apl/2+apl_cf/2],[1+apl/2-apl_cf/2:apl/2+apl_cf/2]);
            disp = linspace(-apl/2,apl/2,length(I_ff(:,1)));
            
            cla(app.CrossSection) % Clear the axes from last time
            
             % Generate The Specified Cross-Section
            switch app.CrossSectionButtonGroup.SelectedObject
                case app.XAxisButton
                    plot(app.CrossSection,disp,I_ff(apl/2+1,:)); % Choose the center of x and plot the intensity cross section for y
                    title(app.CrossSection,'X-Axis Cross-Section')
                    legend(app.CrossSection,"off")
                    xlabel(app.CrossSection,'Displacement From Center in X [pixels]')
                case app.YAxisButton
                    plot(app.CrossSection,disp,I_ff(:,apl/2+1)); % Choose the center of y and plot the intensity cross section for x
                    title(app.CrossSection,'Y-Axis Cross-Section')
                    legend(app.CrossSection, "off");
                    xlabel(app.CrossSection,'Displacement From Center in Y [pixels]')
                case app.BothButton
                    hold(app.CrossSection, "on");
                    plot(app.CrossSection,disp,I_ff(apl/2+1,:)); % Choose the center of x and plot the intensity cross section for y
                    plot(app.CrossSection,disp,I_ff(:,apl/2+1),num2str(linsty2)); % Choose the center of y and plot the intensity cross section for x
                    hold(app.CrossSection, "off");
                    title(app.CrossSection,'X and Y Axis Cross-Sections')
                    legend(app.CrossSection,'X-Axis Cross-Section','Y-Axis Cross-Section')
                    xlabel(app.CrossSection,'Displacement From Center [pixels]')
            end

            app.CrossSection.XLim = [-apl_cf/2 apl_cf/2];

            % Plotting the center of the Far Field Diffraction Pattern
            
            imagesc(app.FarField,disp,disp,I_ff.^0.2); % Visualize the full-field irradiance
            title(app.FarField,{'Far Field Diffraction Pattern', ['of a ',apname,' Aperture']}); % Set the title for each plot
            app.FarField.Colormap = gray;
            
            app.FarField.XLim = [-apl_cf/2 apl_cf/2];
            app.FarField.YLim = [-apl_cf/2 apl_cf/2];
            % app.FarField.YDir = 'normal'; % Flip Command
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            if 2*app.FarField.XLim(2) > 220 
                apl_cf =  2*app.FarField.XLim(2)-200; % Zooming In
            else
                apl_cf = 20; % Zoom In Maximum
            end
            
            % Adjust limits
            app.FarField.XLim = [-apl_cf/2 apl_cf/2];
            app.FarField.YLim = [-apl_cf/2 apl_cf/2];
            
            app.CrossSection.XLim = [-apl_cf/2 apl_cf/2];
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            if 2*app.FarField.XLim(2) < 4800 
                apl_cf =  2*app.FarField.XLim(2)+200; % Zooming Out
            else
                apl_cf = 5000; % Zoom Out Maximum
            end
            
            % Adjust limits
            app.FarField.XLim = [-apl_cf/2 apl_cf/2];
            app.FarField.YLim = [-apl_cf/2 apl_cf/2];
            
            app.CrossSection.XLim = [-apl_cf/2 apl_cf/2];
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            cla(app.Aperture)
            cla(app.CrossSection)
            cla(app.FarField)   
            legend(app.CrossSection, "off");
            
        end

        % Value changed function: SideLengthEditField
        function SideLengthEditFieldValueChanged(app, event)
            cla(app.Aperture) % Clear the display
            cla(app.CrossSection)
            cla(app.FarField)
            legend(app.CrossSection, "off");
            
        end

        % Selection changed function: CrossSectionButtonGroup
        function CrossSectionButtonGroupSelectionChanged(app, event)
            cla(app.Aperture) % Clear the display
            cla(app.CrossSection)
            cla(app.FarField)
            legend(app.CrossSection, "off");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FarFieldDiffractionModelLabel = uilabel(app.UIFigure);
            app.FarFieldDiffractionModelLabel.Text = 'Far-Field Diffraction Model';
            app.ApertureTypeDropDownLabel = uilabel(app.UIFigure);
            app.ApertureTypeDropDownLabel.Text = 'Aperture Type';
            app.ApertureTypeDropDown = uidropdown(app.UIFigure);
            app.ApertureTypeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Right Triangle', 'Single Slit', 'Double Slit'};
            app.ApertureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ApertureTypeDropDownValueChanged, true);
            app.ApertureTypeDropDown.Value = 'Circle';
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Value = 1;
            app.SideLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthEditFieldLabel.Visible = 'off';
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SideLengthEditFieldValueChanged, true);
            app.SideLengthEditField.Visible = 'off';
            app.SideLengthEditField.Value = 1;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CrossSectionButtonGroup = uibuttongroup(app.UIFigure);
            app.CrossSectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CrossSectionButtonGroupSelectionChanged, true);
            app.CrossSectionButtonGroup.Title = 'Cross-Section';
            app.BothButton = uiradiobutton(app.CrossSectionButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.XAxisButton = uiradiobutton(app.CrossSectionButtonGroup);
            app.XAxisButton.Text = 'X-Axis';
            app.YAxisButton = uiradiobutton(app.CrossSectionButtonGroup);
            app.YAxisButton.Text = 'Y-Axis';
            app.ZoomInButton = uibutton(app.UIFigure, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.UIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.FarField = uiaxes(app.UIFigure);
            title(app.FarField, 'Far Field Diffraction')
            xlabel(app.FarField, 'x [pixels]')
            ylabel(app.FarField, 'y [pixels]')
            app.FarField.XLim = [0 5000];
            app.FarField.YLim = [0 5000];
            app.CrossSection = uiaxes(app.UIFigure);
            title(app.CrossSection, 'Cross Sections of X and Y Axes')
            xlabel(app.CrossSection, 'Displacement From Center [pixels]')
            ylabel(app.CrossSection, 'Intensity [ arb. units]')
            app.CrossSection.XLim = [0 250];
            app.Aperture = uiaxes(app.UIFigure);
            title(app.Aperture, 'Aperture')
            xlabel(app.Aperture, 'x [arb. units]')
            ylabel(app.Aperture, 'y [arb. units]')
            app.Aperture.XLim = [0 5000];
            app.Aperture.YLim = [0 5000];
            app.Aperture.XTick = [0 2500 5000];
            app.Aperture.XTickLabel = {'0'; '2500'; '5000'};
            app.Aperture.YTick = [0 2500 5000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FarField_r0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end